classdef LCR_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        DevicesPanel                  matlab.ui.container.Panel
        LCRLampLabel                  matlab.ui.control.Label
        LCRLamp                       matlab.ui.control.Lamp
        SwitchLabel                   matlab.ui.control.Label
        Switch                        matlab.ui.control.ToggleSwitch
        AddressEditFieldLabel         matlab.ui.control.Label
        LCRGPIBAddress                matlab.ui.control.NumericEditField
        ArduinoLampLabel              matlab.ui.control.Label
        ArduinoLamp                   matlab.ui.control.Lamp
        AddressEditFieldLabel_2       matlab.ui.control.Label
        ArduinoAddress                matlab.ui.control.EditField
        AcquisitionPanel              matlab.ui.container.Panel
        DirectoryTextArea_2Label      matlab.ui.control.Label
        DirectoryTextArea             matlab.ui.control.TextArea
        SelectDirectoryButton         matlab.ui.control.StateButton
        FiletagEditFieldLabel         matlab.ui.control.Label
        FiletagEditField              matlab.ui.control.EditField
        FrequencyHzEditFieldLabel     matlab.ui.control.Label
        FrequencyHzEditField          matlab.ui.control.NumericEditField
        BiasStartVEditFieldLabel      matlab.ui.control.Label
        BiasStartVEditField           matlab.ui.control.NumericEditField
        BiasStopVEditFieldLabel       matlab.ui.control.Label
        BiasStopVEditField            matlab.ui.control.NumericEditField
        StepVEditFieldLabel           matlab.ui.control.Label
        BiasStepVEditField            matlab.ui.control.NumericEditField
        StartButton                   matlab.ui.control.StateButton
        AbortButton                   matlab.ui.control.Button
        TestCoxFEditFieldLabel        matlab.ui.control.Label
        TestCoxFEditField             matlab.ui.control.NumericEditField
        RepetitionsEditFieldLabel     matlab.ui.control.Label
        RepetitionsEditField          matlab.ui.control.NumericEditField
        IntegrationTimeDropDownLabel  matlab.ui.control.Label
        IntegrationTimeDropDown       matlab.ui.control.DropDown
        LevelVEditFieldLabel          matlab.ui.control.Label
        LevelVEditField               matlab.ui.control.NumericEditField
        AvgRateDropDownLabel          matlab.ui.control.Label
        AvgRateDropDown               matlab.ui.control.DropDown
        LoadconfigButton              matlab.ui.control.Button
        GraphsPanel                   matlab.ui.container.Panel
        CVAxes                        matlab.ui.control.UIAxes
        SweeprateVsEditFieldLabel     matlab.ui.control.Label
        SweeprateEditField            matlab.ui.control.NumericEditField
        CapacitanceFEditFieldLabel    matlab.ui.control.Label
        CapacitanceEditField          matlab.ui.control.NumericEditField
        ElapsedTimesEditFieldLabel    matlab.ui.control.Label
        ElapsedTimeEditField          matlab.ui.control.NumericEditField
        CurrentBiasVEditFieldLabel    matlab.ui.control.Label
        CurrentBiasVEditField         matlab.ui.control.NumericEditField
        DatapointEditFieldLabel       matlab.ui.control.Label
        DatapointEditField            matlab.ui.control.EditField
        DeviceEditFieldLabel          matlab.ui.control.Label
        DeviceEditField               matlab.ui.control.EditField
        CorrectionsPanel              matlab.ui.container.Panel
        LengthmEditFieldLabel         matlab.ui.control.Label
        LengthEditField               matlab.ui.control.NumericEditField
        LengthCorrectionButton        matlab.ui.control.Button
        OpenCorrectionButton          matlab.ui.control.Button
        ShortCorrectionButton         matlab.ui.control.Button
    end


    properties (Access = public)
        powerState      = 0; % Tells if the power is on or off
        acquisitionFlag = 0; % 1 if app is taken measurement 0 otherwise
        handle_lcr      = 0; % This is the handle to the LCR meter connection
        h_arduino       = 0; % This is the handle to the Arduino connection
        stopFlag        = 0; % This tells the program to stop taking measurements
    end


    methods (Access = private)

        % Value changed function: Switch
        function powerButton(app, event)
            value = app.Switch.Value;
            if app.powerState == 0 
                app.handle_lcr = ConnectDevices(app);
                app.powerState = 1;
            else
                DisconnectDevices(app);
                app.handle_lcr = 0;
                app.powerState = 0;
            end % if app.powerState == 0 
        end

        % Button pushed function: LoadconfigButton
        function getConfigFile(app, event)
            [file,path] = uigetfile('*.mat');
            loadConfigFromFile(app,file,path);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.DevicesPanel = uipanel(app.UIFigure);
            app.DevicesPanel.Title = 'Devices';
            app.LCRLampLabel = uilabel(app.DevicesPanel);
            app.LCRLampLabel.Text = 'LCR';
            app.LCRLamp = uilamp(app.DevicesPanel);
            app.SwitchLabel = uilabel(app.DevicesPanel);
            app.SwitchLabel.Text = 'Switch';
            app.Switch = uiswitch(app.DevicesPanel, 'toggle');
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @powerButton, true);
            app.AddressEditFieldLabel = uilabel(app.DevicesPanel);
            app.AddressEditFieldLabel.Text = 'Address';
            app.LCRGPIBAddress = uieditfield(app.DevicesPanel, 'numeric');
            app.LCRGPIBAddress.Editable = 'off';
            app.LCRGPIBAddress.Value = 17;
            app.ArduinoLampLabel = uilabel(app.DevicesPanel);
            app.ArduinoLampLabel.Text = 'Arduino';
            app.ArduinoLamp = uilamp(app.DevicesPanel);
            app.AddressEditFieldLabel_2 = uilabel(app.DevicesPanel);
            app.AddressEditFieldLabel_2.Text = 'Address';
            app.ArduinoAddress = uieditfield(app.DevicesPanel, 'text');
            app.ArduinoAddress.Editable = 'off';
            app.ArduinoAddress.Value = 'COM4';
            app.AcquisitionPanel = uipanel(app.UIFigure);
            app.AcquisitionPanel.Title = 'Acquisition Panel';
            app.DirectoryTextArea_2Label = uilabel(app.AcquisitionPanel);
            app.DirectoryTextArea_2Label.Text = 'Directory';
            app.DirectoryTextArea = uitextarea(app.AcquisitionPanel);
            app.SelectDirectoryButton = uibutton(app.AcquisitionPanel, 'state');
            app.SelectDirectoryButton.Text = 'Select Directory';
            app.FiletagEditFieldLabel = uilabel(app.AcquisitionPanel);
            app.FiletagEditFieldLabel.Text = 'File tag:';
            app.FiletagEditField = uieditfield(app.AcquisitionPanel, 'text');
            app.FiletagEditField.Value = 'Auto';
            app.FrequencyHzEditFieldLabel = uilabel(app.AcquisitionPanel);
            app.FrequencyHzEditFieldLabel.Text = 'Frequency (Hz):';
            app.FrequencyHzEditField = uieditfield(app.AcquisitionPanel, 'numeric');
            app.FrequencyHzEditField.Limits = [20 1000000];
            app.FrequencyHzEditField.RoundFractionalValues = 'on';
            app.FrequencyHzEditField.Value = 1000000;
            app.BiasStartVEditFieldLabel = uilabel(app.AcquisitionPanel);
            app.BiasStartVEditFieldLabel.Text = 'Bias Start (V):';
            app.BiasStartVEditField = uieditfield(app.AcquisitionPanel, 'numeric');
            app.BiasStartVEditField.Limits = [-40 40];
            app.BiasStartVEditField.Value = -1;
            app.BiasStopVEditFieldLabel = uilabel(app.AcquisitionPanel);
            app.BiasStopVEditFieldLabel.Text = 'Bias Stop (V):';
            app.BiasStopVEditField = uieditfield(app.AcquisitionPanel, 'numeric');
            app.BiasStopVEditField.Limits = [-40 40];
            app.BiasStopVEditField.Value = 1;
            app.StepVEditFieldLabel = uilabel(app.AcquisitionPanel);
            app.StepVEditFieldLabel.Text = 'Step (V):';
            app.BiasStepVEditField = uieditfield(app.AcquisitionPanel, 'numeric');
            app.BiasStepVEditField.Limits = [-30 Inf];
            app.BiasStepVEditField.Value = 0.1;
            app.StartButton = uibutton(app.AcquisitionPanel, 'state');
            app.StartButton.Text = 'Start';
            app.AbortButton = uibutton(app.AcquisitionPanel, 'push');
            app.AbortButton.Text = 'Abort';
            app.TestCoxFEditFieldLabel = uilabel(app.AcquisitionPanel);
            app.TestCoxFEditFieldLabel.Text = 'Test Cox (F):';
            app.TestCoxFEditField = uieditfield(app.AcquisitionPanel, 'numeric');
            app.RepetitionsEditFieldLabel = uilabel(app.AcquisitionPanel);
            app.RepetitionsEditFieldLabel.Text = 'Repetitions:';
            app.RepetitionsEditField = uieditfield(app.AcquisitionPanel, 'numeric');
            app.IntegrationTimeDropDownLabel = uilabel(app.AcquisitionPanel);
            app.IntegrationTimeDropDownLabel.Text = 'Integration Time';
            app.IntegrationTimeDropDown = uidropdown(app.AcquisitionPanel);
            app.IntegrationTimeDropDown.Items = {'Short', 'Medium', 'Long'};
            app.IntegrationTimeDropDown.Value = 'Short';
            app.LevelVEditFieldLabel = uilabel(app.AcquisitionPanel);
            app.LevelVEditFieldLabel.Text = 'Level (V)';
            app.LevelVEditField = uieditfield(app.AcquisitionPanel, 'numeric');
            app.LevelVEditField.Limits = [0.005 0.3];
            app.LevelVEditField.Value = 0.005;
            app.AvgRateDropDownLabel = uilabel(app.AcquisitionPanel);
            app.AvgRateDropDownLabel.Text = 'Avg Rate';
            app.AvgRateDropDown = uidropdown(app.AcquisitionPanel);
            app.AvgRateDropDown.Items = {'2', '4', '8', '16', '32', '64', '128'};
            app.AvgRateDropDown.Value = '2';
            app.LoadconfigButton = uibutton(app.AcquisitionPanel, 'push');
            app.LoadconfigButton.ButtonPushedFcn = createCallbackFcn(app, @getConfigFile, true);
            app.LoadconfigButton.Text = 'Load config';
            app.GraphsPanel = uipanel(app.UIFigure);
            app.GraphsPanel.Title = 'Graphs Panel';
            app.CVAxes = uiaxes(app.GraphsPanel);
            title(app.CVAxes, 'C-V')
            xlabel(app.CVAxes, 'Bias (V)')
            ylabel(app.CVAxes, 'Capacitance (F)')
            app.SweeprateVsEditFieldLabel = uilabel(app.GraphsPanel);
            app.SweeprateVsEditFieldLabel.Text = 'Sweep rate (V/s):';
            app.SweeprateEditField = uieditfield(app.GraphsPanel, 'numeric');
            app.SweeprateEditField.Editable = 'off';
            app.CapacitanceFEditFieldLabel = uilabel(app.GraphsPanel);
            app.CapacitanceFEditFieldLabel.Text = 'Capacitance (F):';
            app.CapacitanceEditField = uieditfield(app.GraphsPanel, 'numeric');
            app.CapacitanceEditField.Editable = 'off';
            app.ElapsedTimesEditFieldLabel = uilabel(app.GraphsPanel);
            app.ElapsedTimesEditFieldLabel.Text = 'Elapsed Time (s):';
            app.ElapsedTimeEditField = uieditfield(app.GraphsPanel, 'numeric');
            app.ElapsedTimeEditField.Limits = [0 Inf];
            app.ElapsedTimeEditField.Editable = 'off';
            app.CurrentBiasVEditFieldLabel = uilabel(app.GraphsPanel);
            app.CurrentBiasVEditFieldLabel.Text = 'Current Bias (V):';
            app.CurrentBiasVEditField = uieditfield(app.GraphsPanel, 'numeric');
            app.CurrentBiasVEditField.Limits = [-40 40];
            app.CurrentBiasVEditField.Editable = 'off';
            app.DatapointEditFieldLabel = uilabel(app.GraphsPanel);
            app.DatapointEditFieldLabel.Text = 'Datapoint:';
            app.DatapointEditField = uieditfield(app.GraphsPanel, 'text');
            app.DatapointEditField.Editable = 'off';
            app.DeviceEditFieldLabel = uilabel(app.GraphsPanel);
            app.DeviceEditFieldLabel.Text = 'Device';
            app.DeviceEditField = uieditfield(app.GraphsPanel, 'text');
            app.DeviceEditField.Editable = 'off';
            app.CorrectionsPanel = uipanel(app.UIFigure);
            app.CorrectionsPanel.Title = 'Corrections';
            app.CorrectionsPanel.Visible = 'off';
            app.LengthmEditFieldLabel = uilabel(app.CorrectionsPanel);
            app.LengthmEditFieldLabel.Text = 'Length (m):';
            app.LengthEditField = uieditfield(app.CorrectionsPanel, 'numeric');
            app.LengthEditField.Limits = [0 Inf];
            app.LengthCorrectionButton = uibutton(app.CorrectionsPanel, 'push');
            app.LengthCorrectionButton.Text = 'Length Correction';
            app.OpenCorrectionButton = uibutton(app.CorrectionsPanel, 'push');
            app.OpenCorrectionButton.Text = '"Open" Correction';
            app.ShortCorrectionButton = uibutton(app.CorrectionsPanel, 'push');
            app.ShortCorrectionButton.Text = '"Short" Correction';
        end
    end

    methods (Access = public)

        % Construct app
        function app = LCR_GUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end